classdef SmartHospital < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ECGFFTTab                  matlab.ui.container.Tab
        ALERTSTATUSTextArea        matlab.ui.control.TextArea
        ALERTSTATUSTextAreaLabel   matlab.ui.control.Label
        RunMonitoringSytemButton   matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        RespirationTab             matlab.ui.container.Tab
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        HRVTab                     matlab.ui.container.Tab
        UIAxes7                    matlab.ui.control.UIAxes
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes5                    matlab.ui.control.UIAxes
        SpOBPTab                   matlab.ui.container.Tab
        PatientstatusLamp          matlab.ui.control.Lamp
        PatientstatusLampLabel     matlab.ui.control.Label
        HeartRateGauge             matlab.ui.control.Gauge
        HeartRateGaugeLabel        matlab.ui.control.Label
        SpOGauge                   matlab.ui.control.Gauge
        SpOGaugeLabel              matlab.ui.control.Label
        UIAxes9                    matlab.ui.control.UIAxes
        UIAxes8                    matlab.ui.control.UIAxes
        SummaryTab                 matlab.ui.container.Tab
        HospitalLabel              matlab.ui.control.Label
        OverallStatusLamp          matlab.ui.control.Lamp
        OverallStatusLampLabel     matlab.ui.control.Label
        SystemReportTextArea       matlab.ui.control.TextArea
        SystemReportTextAreaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunMonitoringSytemButton
        function RunMonitoringSytemButtonPushed(app, event)
            addpath(genpath(pwd));
            fs = 500;
            duration = 10;

            % Generate ECG
            [ecg, t] = m1_generate_ecg(fs, duration);

            % Plot ECG
            plot(app.UIAxes, t, ecg);

            title(app.UIAxes, 'ECG Signal');

            xlabel(app.UIAxes, 'Time (s)');

            ylabel(app.UIAxes, 'Amplitude');

            grid(app.UIAxes, 'on');

            % FFT Analysis
            N = length(ecg);

            Y = fft(ecg);

            mags = 2*abs(Y(1:floor(N/2)+1))/N;

            freqs = fs*(0:floor(N/2))/N;

            plot(app.UIAxes2, freqs, mags);

            xlim(app.UIAxes2, [0 10]);

            title(app.UIAxes2, 'FFT Spectrum');

            xlabel(app.UIAxes2, 'Frequency (Hz)');

            ylabel(app.UIAxes2, 'Magnitude');

            grid(app.UIAxes2, 'on');

            % Alert system
            [alert, msg] = m3_smart_alert(ecg, fs);

            if alert
                app.ALERTSTATUSTextArea.Value = ['ALERT: ' msg];
            else
                app.ALERTSTATUSTextArea.Value = ['STATUS: ' msg];    
            
            end
            % =====================================
            % RESPIRATION SIGNAL
            % =====================================

            [resp, t_resp] = ...
                m5_generate_respiration(fs, duration);

            % Plot respiration
            plot(app.UIAxes3, ...
                t_resp, resp, ...
                'g', 'LineWidth', 1.2);

            title(app.UIAxes3, ...
                'Respiration Signal');

            xlabel(app.UIAxes3, ...
                'Time (s)');

            ylabel(app.UIAxes3, ...
                'Amplitude');

            grid(app.UIAxes3, 'on');

            % =====================================
            % AIR QUALITY
            % =====================================

            % Simulated CO2
            co2 = 600 + ...
                100*sin(2*pi*0.002*t_resp) + ...
                20*randn(size(t_resp));

            plot(app.UIAxes4, ...
                t_resp, co2, ...
                'b', 'LineWidth', 1.2);

            title(app.UIAxes4, ...
                'Air Quality (CO2)');

            xlabel(app.UIAxes4, ...
                'Time (s)');

            ylabel(app.UIAxes4, ...
                'CO2 ppm');

            grid(app.UIAxes4, 'on');
            % =====================================
            % HRV ANALYSIS
            % =====================================

            [rr_int, rr_t] = ...
                m6_extract_rr(ecg, t, fs);

            % RR Tachogram
            plot(app.UIAxes5, ...
                rr_t, rr_int*1000, ...
                'ro-','LineWidth',1.2);

            title(app.UIAxes5, ...
                'RR Interval Tachogram');

            xlabel(app.UIAxes5, ...
                'Time (s)');

            ylabel(app.UIAxes5, ...
                'RR Interval (ms)');

            grid(app.UIAxes5, 'on');

            % Histogram
            histogram(app.UIAxes6, ...
                rr_int*1000, 15);

            title(app.UIAxes6, ...
                'RR Interval Distribution');

            xlabel(app.UIAxes6, ...
                'RR Interval (ms)');

            ylabel(app.UIAxes6, ...
                'Count');

            % HRV Spectrum
            Nrr = length(rr_int);

            Yrr = fft(rr_int);

            mags_rr = 2*abs( ...
                Yrr(1:floor(Nrr/2)+1))/Nrr;

            freqs_rr = ...
                4*(0:floor(Nrr/2))/Nrr;

            plot(app.UIAxes7, ...
                freqs_rr, mags_rr, ...
                'k','LineWidth',1.2);

            xlim(app.UIAxes7, [0 0.5]);

            title(app.UIAxes7, ...
                'HRV Frequency Spectrum');

            xlabel(app.UIAxes7, ...
                'Frequency (Hz)');

            ylabel(app.UIAxes7, ...
                'Magnitude');

            grid(app.UIAxes7, 'on');
            % =====================================
            % PPG / SPO2 / BP
            % =====================================

            [ppg, t_ppg] = ...
                m7_generate_ppg(fs, duration);

            % PPG waveform
            plot(app.UIAxes8, ...   
                t_ppg, ppg, ...
                'm','LineWidth',1.2);

            title(app.UIAxes8, ...
                'PPG Waveform');

            xlabel(app.UIAxes8, ...
                'Time (s)');

            ylabel(app.UIAxes8, ...
                'Amplitude');

            grid(app.UIAxes8, 'on');

            % SpO2
            spo2 = m7_estimate_spo2(ppg);

            app.SpOGauge.Value = spo2;

            % Heart Rate
            app.HeartRateGauge.Value = 72;

            % BP
            [sys_bp, dia_bp, map_val] = ...
                m7_estimate_blood_pressure(ppg, fs);

            % Simulated BP trend
            t_bp = linspace(0,30,300);

            sys_trend = ...
                sys_bp + 5*sin(2*pi*0.03*t_bp);

            dia_trend = ...
                dia_bp + 3*sin(2*pi*0.03*t_bp);

            plot(app.UIAxes9, ...
                t_bp, sys_trend, ...
                'r','LineWidth',1.2);

            hold(app.UIAxes9, 'on');

            plot(app.UIAxes9, ...
                t_bp, dia_trend, ...
                'b','LineWidth',1.2);

            hold(app.UIAxes9, 'off');

            legend(app.UIAxes9, ...
                'Systolic','Diastolic');

            title(app.UIAxes9, ...
                'Blood Pressure Trend');

            xlabel(app.UIAxes9, ...
                'Time (s)');

            ylabel(app.UIAxes9, ...
                'Pressure (mmHg)');

            grid(app.UIAxes9, 'on');

            % Lamp
            % ============================
            % OVERALL SYSTEM STATUS
            % ============================

            system_bad = false;

            % ECG alert
            if alert
                system_bad = true;
            end

            % Oxygen alert
            if spo2 < 95
                system_bad = true;
            end

            % Blood pressure alert
            if sys_bp > 140 || dia_bp > 90 || sys_bp < 90
                system_bad = true;
            end

            % Final lamp status
            if system_bad

                app.OverallStatusLamp.Color = 'red';
                app.PatientstatusLamp.Color = 'red';

                status_text = 'CRITICAL / WARNING';

            else

                app.OverallStatusLamp.Color = 'green';
                app.PatientstatusLamp.Color = 'green';

                status_text = 'NORMAL';

            end

            summary = sprintf([ ...
                'SMART HOSPITAL STATUS\n\n' ...
                'ECG Monitoring : ACTIVE\n' ...
                'Respiration    : STABLE\n' ...
                'SpO2           : %.1f %%\n' ...
                'Blood Pressure : %.0f / %.0f mmHg\n' ...
                'System Status  : %s\n\n' ...
                'All Sensors Operational'], ...
                spo2, sys_bp, dia_bp, status_text);

            app.SystemReportTextArea.Value = splitlines(summary);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ECGFFTTab = uitab(app.TabGroup);
            app.ECGFFTTab.Title = 'ECG & FFT';
            app.UIAxes = uiaxes(app.ECGFFTTab);
            title(app.UIAxes, 'ECG signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.ECGFFTTab);
            title(app.UIAxes2, 'FFT Spectrum')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.ECGFFTTab);
            app.Label.Text = 'Smart Hospital Monitoring Sytem';
            app.RunMonitoringSytemButton = uibutton(app.ECGFFTTab, 'push');
            app.RunMonitoringSytemButton.ButtonPushedFcn = createCallbackFcn(app, @RunMonitoringSytemButtonPushed, true);
            app.RunMonitoringSytemButton.Text = 'Run Monitoring Sytem';
            app.ALERTSTATUSTextAreaLabel = uilabel(app.ECGFFTTab);
            app.ALERTSTATUSTextAreaLabel.Text = 'ALERT/STATUS';
            app.ALERTSTATUSTextArea = uitextarea(app.ECGFFTTab);
            app.ALERTSTATUSTextArea.Value = {'Off'};
            app.RespirationTab = uitab(app.TabGroup);
            app.RespirationTab.Title = 'Respiration';
            app.UIAxes3 = uiaxes(app.RespirationTab);
            title(app.UIAxes3, 'Respiration')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RespirationTab);
            title(app.UIAxes4, 'Air quality')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.HRVTab = uitab(app.TabGroup);
            app.HRVTab.Title = 'HRV';
            app.UIAxes5 = uiaxes(app.HRVTab);
            title(app.UIAxes5, 'RR intervals')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.HRVTab);
            title(app.UIAxes6, 'Histogram')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.HRVTab);
            title(app.UIAxes7, 'HRV spectrum')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.SpOBPTab = uitab(app.TabGroup);
            app.SpOBPTab.Title = 'SpO₂ & BP';
            app.UIAxes8 = uiaxes(app.SpOBPTab);
            title(app.UIAxes8, 'PPG waveform')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.SpOBPTab);
            title(app.UIAxes9, 'BP trends')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.SpOGaugeLabel = uilabel(app.SpOBPTab);
            app.SpOGaugeLabel.Text = 'SpO₂';
            app.SpOGauge = uigauge(app.SpOBPTab, 'circular');
            app.HeartRateGaugeLabel = uilabel(app.SpOBPTab);
            app.HeartRateGaugeLabel.Text = 'Heart Rate';
            app.HeartRateGauge = uigauge(app.SpOBPTab, 'circular');
            app.PatientstatusLampLabel = uilabel(app.SpOBPTab);
            app.PatientstatusLampLabel.Text = 'Patient status';
            app.PatientstatusLamp = uilamp(app.SpOBPTab);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.SystemReportTextAreaLabel = uilabel(app.SummaryTab);
            app.SystemReportTextAreaLabel.Text = 'System Report';
            app.SystemReportTextArea = uitextarea(app.SummaryTab);
            app.OverallStatusLampLabel = uilabel(app.SummaryTab);
            app.OverallStatusLampLabel.Text = 'Overall Status';
            app.OverallStatusLamp = uilamp(app.SummaryTab);
            app.HospitalLabel = uilabel(app.SummaryTab);
            app.HospitalLabel.Text = 'Hospital';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartHospital

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end